classdef IntegrateChromatogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FolderlocationEditField       matlab.ui.control.EditField
        FolderlocationEditFieldLabel  matlab.ui.control.Label
        Integrateto0CheckBox          matlab.ui.control.CheckBox
        ChromatogramIntegratorLabel   matlab.ui.control.Label
        CSVexportnameEditField        matlab.ui.control.EditField
        CSVexportnameEditFieldLabel   matlab.ui.control.Label
        yMaxSpinner                   matlab.ui.control.Spinner
        yMaxSpinnerLabel              matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ChromatogramSpinner           matlab.ui.control.Spinner
        ChromatogramSpinnerLabel      matlab.ui.control.Label
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        ProcessButton                 matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        allData % All the chromatogram data
        extractedPeaks % All the integration data
        names % Name of each file
    end
    
    methods (Access = private)
        
        % Update the graph with integration lines to check the
        % integration has been performed correctly.
        function plotGraph(app)
            % Set spinner value 
            graphInd = app.ChromatogramSpinner.Value;

            % Plot the graph related to the spinner value
            plot(app.UIAxes, app.allData{1,graphInd}(:,1),app.allData{1,graphInd}(:,2))
            hold(app.UIAxes, "on")

            % Overlay the lines that the peaks are integrated between
            for x = 1:size(app.UITable.Data,1)
                xline(app.UIAxes, app.UITable.Data(x,2), "color", "red")
                xline(app.UIAxes, app.UITable.Data(x,3), "color", "red")
            end
            
            % If the peak is not being integrated to zero, draw a red line
            % from where the first xline crosses the curve to where the
            % second xline crosses the curve, for each peak being
            % integrated. This shows visually the area that is being
            % integrated.
            if app.Integrateto0CheckBox.Value ~= 1
                for peakNumb = 1:size(app.UITable.Data,1)
                    % Get time index points where lines intersect the
                    % chromatogram
                    timeInd = app.allData{graphInd}(:,1)<app.UITable.Data(peakNumb, 3) & app.allData{graphInd}(:,1)>app.UITable.Data(peakNumb, 2);
                    % Extract the time and intensity data using the index
                    times = app.allData{graphInd}(timeInd,1);
                    intens = app.allData{graphInd}(timeInd,2);
                    % Plot the lines
                    plot(app.UIAxes,[times(1) times(end)], [intens(1) intens(end)], "color", "red")
                end
            end

            hold(app.UIAxes, "off")

            % Set the y axis limit based on what the spinner value is set
            % too.
            ylim(app.UIAxes, [0 app.yMaxSpinner.Value])
            
            % Update label text so the user can identify the graph
            app.Label.Text = app.names(graphInd);
        end
        
        % Save the data to an .xlsx format containing the integrated peak
        % values for each peak defined in the UITable.
        function saveData(app)
    
            % Generate a 1xX dimensional table of all the names of each
            % file processed and set the title to "Names"
            saveData = table(app.names');
            saveData.Properties.VariableNames = "Names";
            
            % Extract each peak name/label and convert to string (should be
            % a number in the table)
            peakNames = string(app.extractedPeaks{1, 1}(:,1));

            % Go through each file processed and add the integration data
            % next to it.
            formatedData = [];
            for x = 1:length(app.names)
                formatedData = [formatedData;app.extractedPeaks{1, x}(:,2)'];
            end
            
            % Convert to table and update with headings
            formatedData = array2table(formatedData);
            formatedData.Properties.VariableNames = peakNames;
            
            % Combine table with file names and write to CSV file
            saveData = [saveData formatedData];
            writetable(saveData, app.CSVexportnameEditField.Value)
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Preset integration data
            app.UITable.Data = [29.5, 29.16, 29.78;
                                30.1, 29.78, 30.42;
                                3.8, 3.68, 3.94;
                                4.9, 4.76, 5.10;
                                32.5, 32.08, 32.81];

            % Preset folder location
            app.FolderlocationEditField.Value = "Chromatograms";
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            % Obtain infomation about the folder and obtain all the file
            % names. location + names = full file path
            MyFolderInfo = dir(app.FolderlocationEditField.Value);
            MyFolderInfo = struct2cell(MyFolderInfo);
            app.names = cellfun(@string, MyFolderInfo(1,:));
            location = cellfun(@string, MyFolderInfo(2,:));
            
            app.names = app.names(3:end);
            location = location(3:end) + "/" + app.names;

            % Predefine cell
            app.allData = cell(1,1);
            
            % Go through each time-intensity file and read only the first
            % two rows (incase any other rows are present). Convert table
            % to array and then store in allData cell. Normalise the data
            % by subtracting the first intensity point from all the
            % intensity data points.
            for fileInd = 1:length(app.names)
                dataTab = readtable(location(fileInd));
                dataTab = dataTab(:,[1 2]);
                app.allData(fileInd) = {table2array(dataTab)};
                app.allData{fileInd}(:,2) = app.allData{fileInd}(:,2) - app.allData{fileInd}(1,2);
            end
            
            % Predefine cell
            app.extractedPeaks = cell(1,1);
            
            % Loop through each chromatogram/datafile
            for chromNumb = 1:length(app.allData)
                initExtractPeak = [];

                % Loop through each peak defined in the table
                for peakNumb = 1:size(app.UITable.Data,1)
                    
                    % Get time index points where lines intersect the
                    % chromatogram. Then use this index to get the time and
                    % intensity data.
                    timeInd = app.allData{chromNumb}(:,1)<app.UITable.Data(peakNumb, 3) & app.allData{chromNumb}(:,1)>app.UITable.Data(peakNumb, 2);
                    times = app.allData{chromNumb}(timeInd,1);
                    intens = app.allData{chromNumb}(timeInd,2);

                    if app.Integrateto0CheckBox.Value == 1
                        % If integrating to 0, then just perfom the trapz
                        % function with times and intens
                        area = trapz(times, intens);
                    else
                        % If area under curve is not desired, subtract the
                        % trapesium formed underneith the red line between
                        % the two xlines. 
                        area = trapz(times, intens) - trapz([times(1) times(end)], [intens(1) intens(end)]);
                    end
                    
                    % set this data in the correct format
                    initExtractPeak = [initExtractPeak; app.UITable.Data(peakNumb, 1) area];
                end

                % Add to the cell.
                app.extractedPeaks(chromNumb) = {initExtractPeak};
            end

            % Set the limit for the spinner
            app.ChromatogramSpinner.Limits = [1 length(app.names)];

            % Plot graph and save data
            app.plotGraph()
            app.saveData()

        end

        % Value changed function: ChromatogramSpinner
        function ChromatogramSpinnerValueChanged(app, event)
            % Plot graph function when spinner value is changed
            app.plotGraph()
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % When plus button is pushed, add a row of zeros to the table
            app.UITable.Data(end+1,:) = 0; % Add Row of zeros
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if size(app.UITable.Data,1) ~= 1
                 % When minus button is pushed, add a row of zeros to the
                 % table so long as there is still at least one row.
                app.UITable.Data(end,:) = []; % Delete Row
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time'; 'Min val'; 'Max val'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.ChromatogramSpinnerLabel = uilabel(app.UIFigure);
            app.ChromatogramSpinnerLabel.Text = 'Chromatogram';
            app.ChromatogramSpinner = uispinner(app.UIFigure);
            app.ChromatogramSpinner.ValueChangedFcn = createCallbackFcn(app, @ChromatogramSpinnerValueChanged, true);
            app.ChromatogramSpinner.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.yMaxSpinnerLabel = uilabel(app.UIFigure);
            app.yMaxSpinnerLabel.Text = 'yMax';
            app.yMaxSpinner = uispinner(app.UIFigure);
            app.yMaxSpinner.Value = 40;
            app.CSVexportnameEditFieldLabel = uilabel(app.UIFigure);
            app.CSVexportnameEditFieldLabel.Text = 'CSV export name';
            app.CSVexportnameEditField = uieditfield(app.UIFigure, 'text');
            app.CSVexportnameEditField.Value = 'test.csv';
            app.ChromatogramIntegratorLabel = uilabel(app.UIFigure);
            app.ChromatogramIntegratorLabel.Text = 'Chromatogram Integrator';
            app.Integrateto0CheckBox = uicheckbox(app.UIFigure);
            app.Integrateto0CheckBox.Text = 'Integrate to 0';
            app.FolderlocationEditFieldLabel = uilabel(app.UIFigure);
            app.FolderlocationEditFieldLabel.Text = 'Folder location';
            app.FolderlocationEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntegrateChromatogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end